classdef lab_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SignaltypeDropDownLabel        matlab.ui.control.Label
        SignaltypeDropDown             matlab.ui.control.DropDown
        NumberofpointsEditFieldLabel   matlab.ui.control.Label
        NumberofpointsEditField        matlab.ui.control.NumericEditField
        SignalamplitudeEditFieldLabel  matlab.ui.control.Label
        SignalamplitudeEditField       matlab.ui.control.NumericEditField
        NoisetypeDropDownLabel         matlab.ui.control.Label
        NoisetypeDropDown              matlab.ui.control.DropDown
        NoiseSKOEditFieldLabel         matlab.ui.control.Label
        NoiseSKOEditField              matlab.ui.control.NumericEditField
        PeriodsnumberEditFieldLabel    matlab.ui.control.Label
        PeriodsnumberEditField         matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.Button
        QuitButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            switch app.SignaltypeDropDown.Value
                case "Sin"
                    fc=3;
                    fs=8000;
                    dt=1/fs;
                    t = 0:dt:1;
                    x=cos(2*pi*fc*t);
                    noise_sko=app.NoiseSKOEditField.Value;
                    noisy_signal=awgn(x, noise_sko);
                    plot(app.UIAxes,t, noisy_signal);
                    
                case "Sawtooth"
                    fc=3;
                    fs=8000;
                    dt=1/fs;
                    t = 0:dt:1;
                    x=sawtooth(2*pi*fc*t);
                    plot(app.UIAxes,t, x);
                    
                case "Triangular"
                    xmax=0.5;
                    fc=3;
                    fs=8000;
                    dt=1/fs;
                    t = 0:dt:1;
                    x=sawtooth(2*pi*fc*t, xmax);
                    plot(app.UIAxes,t, x);

                case "Square"
                    xmax=0.5;
                    fc=3;
                    fs=8000;
                    dt=1/fs;
                    t = 0:dt:1;
                    x=square(2*pi*fc*t);
                    plot(app.UIAxes,t, x);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignaltypeDropDownLabel = uilabel(app.UIFigure);
            app.SignaltypeDropDownLabel.Text = 'Signal type';
            app.SignaltypeDropDown = uidropdown(app.UIFigure);
            app.SignaltypeDropDown.Items = {'Sin', 'Sawtooth', 'Triangular', 'Square'};
            app.SignaltypeDropDown.Value = 'Sin';
            app.NumberofpointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalamplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.SignalamplitudeEditFieldLabel.Text = 'Signal amplitude';
            app.SignalamplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisetypeDropDownLabel = uilabel(app.UIFigure);
            app.NoisetypeDropDownLabel.Text = 'Noise type';
            app.NoisetypeDropDown = uidropdown(app.UIFigure);
            app.NoisetypeDropDown.Items = {'Gaussian', 'White noise'};
            app.NoisetypeDropDown.Value = 'Gaussian';
            app.NoiseSKOEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseSKOEditFieldLabel.Text = 'Noise SKO';
            app.NoiseSKOEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodsnumberEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodsnumberEditFieldLabel.Text = 'Periods number';
            app.PeriodsnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Model signal generator')
            app.UIAxes.XTick = [0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end